classdef PhotoBookApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveasPDFButton             matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        SelectimagesButton          matlab.ui.control.Button
        OutputheightEditField       matlab.ui.control.NumericEditField
        OutputheightEditFieldLabel  matlab.ui.control.Label
        OutputwidthEditField        matlab.ui.control.NumericEditField
        OutputwidthEditFieldLabel   matlab.ui.control.Label
        YoffsetSlider               matlab.ui.control.Slider
        YoffsetSliderLabel          matlab.ui.control.Label
        XoffsetSlider               matlab.ui.control.Slider
        XoffsetSliderLabel          matlab.ui.control.Label
        ZoomSlider                  matlab.ui.control.Slider
        ZoomSliderLabel             matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        RotateButton                matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        ImagePaths = {}; % Description
        lastFolder = "";
        originalImage = [];
        rotation = 0;
        zoom = 1;
        offsetX = 0;
        offsetY = 0;
        imageElementHeight = 0;
        imageElementWidth = 0;
        processedImageCell = {};
    end
    
    methods (Access = private)

        function resetImageProp(app)
            app.rotation = 0;
            app.zoom = 1;
            app.offsetX = 0;
            app.offsetY = 0;
        end

        function LoadNextImage(app)
            % Check if there are any images in the image list
            if isempty(app.ImagePaths)
                % No more images in the list, display a message or take appropriate action
                app.StatusLabel.Text = 'Status: No more images to process.';
                return;
            end

            app.StatusLabel.Text = strcat('Status: processing image ', num2str(length(app.processedImageCell)+1));
            
            % Load the next image from the list
            imagePath = app.ImagePaths{1};
            app.ImagePaths(1) = []; % Remove the loaded image path from the list
            
            % Display the image on the app's image component
            % Assuming you have an Image component named 'ImageComponent' in your app
            app.originalImage = im2double(imread(imagePath));
            app.Image.ImageSource = app.changeImageProp(app.originalImage);
        end
        
        function outImage = changeImageProp(app, inImage)
            outImage = inImage;
            outImage = imrotate(outImage, app.rotation);
            outImage = imresize(outImage, app.zoom);
            outImage = app.fitWithinCanvas(outImage);
        end

        function canvas = fitWithinCanvas(app, inImage)
            
            canvas = ones(app.imageElementHeight, app.imageElementWidth, 3);  % Set the canvas to white color

            % Calculate the size and the offset for centering the image on the canvas
            [imageHeight, imageWidth, ~] = size(inImage);
            xOffset = floor((app.imageElementWidth - imageWidth) / 2);
            yOffset = floor((app.imageElementHeight - imageHeight) / 2);

            inImage = imtranslate(inImage, [imageWidth*app.offsetX, imageHeight*app.offsetY],'FillValues',1);
                                   
            if imageWidth >= app.imageElementWidth
                inImage = inImage(:, -xOffset+1 : app.imageElementWidth-xOffset, :);
            end
            if imageHeight >= app.imageElementHeight
                inImage = inImage(-yOffset+1 : app.imageElementHeight-yOffset, :, :);
            end

            % Calculate the size and the offset for centering the image on the canvas
            [imageHeight, imageWidth, ~] = size(inImage);
            xOffset = floor((app.imageElementWidth - imageWidth) / 2);
            yOffset = floor((app.imageElementHeight - imageHeight) / 2);

            % Perform padding based on the dimensions
            canvas(yOffset+1:yOffset+imageHeight, xOffset+1:xOffset+imageWidth, :) = inImage;            
        end

    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.imageElementHeight = app.OutputheightEditField.Value;
            app.imageElementWidth = app.OutputwidthEditField.Value;
        end

        % Button pushed function: SelectimagesButton
        function SelectimagesButtonPushed(app, event)
            
            % Get the last-used folder location
            
            % Prompt the user to select multiple image files
            if (strcmp(app.lastFolder,""))
                [filePaths, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', 'Image Files'}, ...
                    'Select Images', 'MultiSelect', 'on');
            else
                [filePaths, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif', 'Image Files'}, ...
                    'Select Images', app.lastFolder, 'MultiSelect', 'on');
            end
            
            % If no folder was selected or canceled, return
            if isequal(filePaths, 0)
                return;
            end

            % If a single image is selected, convert it to a cell array
            if ~iscell(filePaths)
                filePaths = {filePaths};
            end
            
            % Prepend the path to each file name to get the complete file path
            filePaths = fullfile(path, filePaths);
            
            % Save the selected file paths in a variable
            app.ImagePaths = filePaths;
            
            % Save the current folder location as the last-used folder
            app.lastFolder = path;
            
            LoadNextImage(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.processedImageCell{end+1} = app.changeImageProp(app.originalImage);
            % resetImageProp(app);
            LoadNextImage(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.rotation = mod(app.rotation + 90, 360);
            app.Image.ImageSource = app.changeImageProp(app.originalImage);
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            app.zoom = app.ZoomSlider.Value;
            app.Image.ImageSource = app.changeImageProp(app.originalImage);
            
        end

        % Value changed function: YoffsetSlider
        function YoffsetSliderValueChanged(app, event)
            app.offsetY = app.YoffsetSlider.Value;    
            app.Image.ImageSource = app.changeImageProp(app.originalImage);        
        end

        % Value changed function: XoffsetSlider
        function XoffsetSliderValueChanged(app, event)
            app.offsetX = app.XoffsetSlider.Value;
            app.Image.ImageSource = app.changeImageProp(app.originalImage);
        end

        % Value changed function: OutputheightEditField, 
        % ...and 1 other component
        function OutputSizeFieldValueChanged(app, event)
            app.imageElementHeight = app.OutputheightEditField.Value;
            app.imageElementWidth = app.OutputwidthEditField.Value;
        end

        % Button pushed function: SaveasPDFButton
        function SaveasPDFButtonPushed(app, event)
            SaveImagesAsPDF(app.processedImageCell);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ZoomSliderLabel = uilabel(app.UIFigure);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.UIFigure);
            app.ZoomSlider.Limits = [0.1 1];
            app.ZoomSlider.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Value = 1;
            app.XoffsetSliderLabel = uilabel(app.UIFigure);
            app.XoffsetSliderLabel.Text = 'X offset';
            app.XoffsetSlider = uislider(app.UIFigure);
            app.XoffsetSlider.Limits = [-1 1];
            app.XoffsetSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.XoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @XoffsetSliderValueChanged, true);
            app.XoffsetSlider.MinorTicks = [];
            app.YoffsetSliderLabel = uilabel(app.UIFigure);
            app.YoffsetSliderLabel.Text = 'Y offset';
            app.YoffsetSlider = uislider(app.UIFigure);
            app.YoffsetSlider.Limits = [-1 1];
            app.YoffsetSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.YoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @YoffsetSliderValueChanged, true);
            app.YoffsetSlider.MinorTicks = [];
            app.OutputwidthEditFieldLabel = uilabel(app.UIFigure);
            app.OutputwidthEditFieldLabel.Text = 'Output width';
            app.OutputwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputwidthEditField.ValueChangedFcn = createCallbackFcn(app, @OutputSizeFieldValueChanged, true);
            app.OutputwidthEditField.Value = 1000;
            app.OutputheightEditFieldLabel = uilabel(app.UIFigure);
            app.OutputheightEditFieldLabel.Text = 'Output height';
            app.OutputheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputheightEditField.ValueChangedFcn = createCallbackFcn(app, @OutputSizeFieldValueChanged, true);
            app.OutputheightEditField.Value = 1000;
            app.SelectimagesButton = uibutton(app.UIFigure, 'push');
            app.SelectimagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimagesButtonPushed, true);
            app.SelectimagesButton.Text = 'Select images';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status: ';
            app.SaveasPDFButton = uibutton(app.UIFigure, 'push');
            app.SaveasPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPDFButtonPushed, true);
            app.SaveasPDFButton.Text = 'Save as PDF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhotoBookApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end